classdef Temperatura < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        GridLayout3                matlab.ui.container.GridLayout
        SerialPortSelectorPanel    matlab.ui.container.Panel
        GridLayout5                matlab.ui.container.GridLayout
        SerialPortSelectorKnob     matlab.ui.control.DiscreteKnob
        MonitorPanel               matlab.ui.container.Panel
        GridLayout4                matlab.ui.container.GridLayout
        Panel_5                    matlab.ui.container.Panel
        HumidityGauge              matlab.ui.control.NumericEditField
        HumidityLabel              matlab.ui.control.Label
        Panel_4                    matlab.ui.container.Panel
        TemperatureGauge           matlab.ui.control.NumericEditField
        TemperatureLabel           matlab.ui.control.Label
        Panel_3                    matlab.ui.container.Panel
        SerialPortStatusLamp       matlab.ui.control.Lamp
        SerialPortStatusLampLabel  matlab.ui.control.Label
        Panel_2                    matlab.ui.container.Panel
        StatusIndicator            matlab.ui.control.Lamp
        SystemStatusLabel          matlab.ui.control.Label
        CenterPanel                matlab.ui.container.Panel
        GridLayout7                matlab.ui.container.GridLayout
        HumidityGraph              matlab.ui.control.UIAxes
        TemperatureGraph           matlab.ui.control.UIAxes
        RightPanel                 matlab.ui.container.Panel
        GridLayout2                matlab.ui.container.GridLayout
        Panel2                     matlab.ui.container.Panel
        GridLayout6                matlab.ui.container.GridLayout
        HumidityPanel              matlab.ui.container.Panel
        GridLayout10               matlab.ui.container.GridLayout
        Humidity                   matlab.ui.control.ToggleSwitch
        TemperaturePanel           matlab.ui.container.Panel
        GridLayout9                matlab.ui.container.GridLayout
        Temperature                matlab.ui.control.ToggleSwitch
        SystemPanel                matlab.ui.container.Panel
        GridLayout8                matlab.ui.container.GridLayout
        OnOffSwitch                matlab.ui.control.ToggleSwitch
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    properties (Access = private)
        s % Description
        sensor
    end   

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Temperature
        function TemperatureValueChanged(app, event)
            T = app.Temperature.Value;
            if T == "1"
                % Set the tresholds
                x2 = zeros(1,50);
                while (app.Temperature.Value == "1")
                    % GRAPHING %
                    % Graph the Temp Value
                    y2 = readTemperature(app.sensor);
                    x2 = [x2([2:50]) y2];
                    plot(app.TemperatureGraph, x2, 'r', 'LineWidth', 2);
                    app.TemperatureGauge.Value = y2;
                    pause(0.01);

                    % 
                    %     % Graph the Hum Value
                    y1 = readHumidity(app.sensor);
                    % x1 = [x1([2:50]) y1];
                    % % % plot(app.LDRRawGraph, x1, 'LineWidth', 2);
                    % % % app.LDRVoltageGauge.Value = (5/1023) * y1;
                        app.HumidityGauge.Value = y1;
                    %     pause(0.01);
                    % % DECISION MAKING %

                        
                end
            else
                % clear sensor
                % fclose(app.s);
                pause(0.05);
                % fopen(app.s);
            end
        end

        % Value changed function: OnOffSwitch
        function OnOffSwitchValueChanged(app, event)
            value = app.OnOffSwitch.Value;
            % port = app.PortEditField.Value;
            if value == "On"
                % app.s = arduino("COM5", "Mega2560", Libraries = ["Adafruit/DHT22"]);
                app.sensor = addon(app.s, 'Adafruit/DHT22', 'D3');
                pause(2);
                app.StatusIndicator.Color = [0.39,0.83,0.07];
            else
                app.s = [];
                clear app.sensor
                clear app.s
                app.StatusIndicator.Color = [0.84,0.05,0.20];
            end
        end

        % Callback function: not associated with a component
        function TemperatureSwitchValueChanged(app, event)
            value = app.TemperatureSwitch.Value;
                        value = app.Temperature.Value;
            % arduinoObj = arduino("COM5", "Mega2560", Libraries = ["Adafruit/DHT22"])
            % sensor = addon(app.s, 'Adafruit/DHT22', 'D3')
        if value == "1"
                % AUTOMATIC CONTROL
                % fwrite(app.s, 1);
                % Set the tresholds
                x1 = zeros(1,50);
                x2 = zeros(1,50);
                while (app.Temperature.Value == "1")
                    
                    % GRAPHING %
                        % Graph the Temp Value
                    y2 = readTemperature(app.sensor)
                    x2 = [x2([2:50]) y2];
                    plot(app.TemperatureGraph, x2, 'r', 'LineWidth', 2);
                    app.TemperatureGauge.Value = y2
                    pause(0.01);


                        % Graph the Hum Value
                    % y1 = readHumidity(app.sensor);
                    % x1 = [x1([2:50]) y1];
                    % % plot(app.LDRRawGraph, x1, 'LineWidth', 2);
                    % % app.LDRVoltageGauge.Value = (5/1023) * y1;
                        % app.HumidityGauge.Value = y1
                        pause(0.01);
                    % DECISION MAKING %

                        
                end
        else
                % clear sensor
                % fclose(app.s);
                pause(0.05);
                % fopen(app.s);
        end
        end

        % Value changed function: SerialPortSelectorKnob
        function SerialPortSelectorKnobValueChanged(app, event)
            k = app.SerialPortSelectorKnob.Value;
            if strcmp(k,'COM1')
                app.s = arduino("COM1", "Mega2560", Libraries = ["Adafruit/DHT22"]);
                app.SerialPortStatusLamp.Color = [0.39,0.83,0.07];
            elseif strcmp(k,'COM2')
                app.s = arduino("COM2", "Mega2560", Libraries = ["Adafruit/DHT22"]);
                app.SerialPortStatusLamp.Color = [0.39,0.83,0.07];
            elseif strcmp(k,'COM3')
                app.s = arduino("COM3", "Mega2560", Libraries = ["Adafruit/DHT22"]);
                app.SerialPortStatusLamp.Color = [0.39,0.83,0.07];
            elseif strcmp(k,'COM4')
                app.s = arduino("COM4", "Mega2560", Libraries = ["Adafruit/DHT22"]);
                app.SerialPortStatusLamp.Color = [0.39,0.83,0.07];
            elseif strcmp(k,'COM5')
                app.s = arduino("COM5", "Mega2560", Libraries = ["Adafruit/DHT22"]);
                app.SerialPortStatusLamp.Color = [0.39,0.83,0.07];
            elseif strcmp(k,'COM6')
                app.s = arduino("COM6", "Mega2560", Libraries = ["Adafruit/DHT22"]);
                app.SerialPortStatusLamp.Color = [0.39,0.83,0.07];
            elseif strcmp(k,'COM7');
                app.s = arduino("COM1", "Mega2560", Libraries = ["Adafruit/DHT22"]);
                app.SerialPortStatusLamp.Color = [0.39,0.83,0.07];
            else
                app.SerialPortStatusLamp.Color = [0.84,0.05,0.20];
            end
        end

        % Value changed function: Humidity
        function HumidityValueChanged(app, event)
            H = app.Humidity.Value;
            if H == "1"
                % Set the tresholds
                x1 = zeros(1,50);
            while (app.Humidity.Value == "1")
                    % GRAPHING %
                    % Graph the Hum Value
                    y1 = readHumidity(app.sensor);
                    x1 = [x1([2:50]) y1];
                    plot(app.HumidityGraph, x1, 'b', 'LineWidth', 2);
                    app.HumidityGauge.Value = y1;
                    pause(0.01);
            end
            else
                % clear sensor
                % fclose(app.s);
                pause(0.05);
                % fopen(app.s);
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {208, '1x', 161};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.LeftPanel);
            app.MonitorPanel = uipanel(app.GridLayout3);
            app.MonitorPanel.Title = 'Monitor';
            app.GridLayout4 = uigridlayout(app.MonitorPanel);
            app.Panel_2 = uipanel(app.GridLayout4);
            app.SystemStatusLabel = uilabel(app.Panel_2);
            app.SystemStatusLabel.Text = 'System Status';
            app.StatusIndicator = uilamp(app.Panel_2);
            app.Panel_3 = uipanel(app.GridLayout4);
            app.SerialPortStatusLampLabel = uilabel(app.Panel_3);
            app.SerialPortStatusLampLabel.Text = 'Serial Port Status';
            app.SerialPortStatusLamp = uilamp(app.Panel_3);
            app.Panel_4 = uipanel(app.GridLayout4);
            app.TemperatureLabel = uilabel(app.Panel_4);
            app.TemperatureLabel.Text = 'Temperature (°C)';
            app.TemperatureGauge = uieditfield(app.Panel_4, 'numeric');
            app.Panel_5 = uipanel(app.GridLayout4);
            app.HumidityLabel = uilabel(app.Panel_5);
            app.HumidityLabel.Text = 'Humidity (%)';
            app.HumidityGauge = uieditfield(app.Panel_5, 'numeric');
            app.SerialPortSelectorPanel = uipanel(app.GridLayout3);
            app.SerialPortSelectorPanel.Title = 'Serial Port Selector';
            app.GridLayout5 = uigridlayout(app.SerialPortSelectorPanel);
            app.SerialPortSelectorKnob = uiknob(app.GridLayout5, 'discrete');
            app.SerialPortSelectorKnob.Items = {'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7'};
            app.SerialPortSelectorKnob.ValueChangedFcn = createCallbackFcn(app, @SerialPortSelectorKnobValueChanged, true);
            app.SerialPortSelectorKnob.Value = 'COM1';
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout7 = uigridlayout(app.CenterPanel);
            app.TemperatureGraph = uiaxes(app.GridLayout7);
            title(app.TemperatureGraph, 'Temperature - Time')
            xlabel(app.TemperatureGraph, 'Time Interval')
            ylabel(app.TemperatureGraph, 'Temperature (°C)')
            zlabel(app.TemperatureGraph, 'Z')
            app.TemperatureGraph.XLim = [0 20];
            app.TemperatureGraph.YLim = [0 50];
            app.HumidityGraph = uiaxes(app.GridLayout7);
            title(app.HumidityGraph, 'Humidity - Time')
            xlabel(app.HumidityGraph, 'Time Interval')
            ylabel(app.HumidityGraph, 'Humidity (%)')
            zlabel(app.HumidityGraph, 'Z')
            app.HumidityGraph.XLim = [0 20];
            app.HumidityGraph.YLim = [0 100];
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.Panel2 = uipanel(app.GridLayout2);
            app.Panel2.Title = 'Panel2';
            app.GridLayout6 = uigridlayout(app.Panel2);
            app.SystemPanel = uipanel(app.GridLayout6);
            app.SystemPanel.Title = 'System';
            app.GridLayout8 = uigridlayout(app.SystemPanel);
            app.OnOffSwitch = uiswitch(app.GridLayout8, 'toggle');
            app.OnOffSwitch.ValueChangedFcn = createCallbackFcn(app, @OnOffSwitchValueChanged, true);
            app.TemperaturePanel = uipanel(app.GridLayout6);
            app.TemperaturePanel.Title = 'Temperature';
            app.GridLayout9 = uigridlayout(app.TemperaturePanel);
            app.Temperature = uiswitch(app.GridLayout9, 'toggle');
            app.Temperature.Items = {'0', '1'};
            app.Temperature.ValueChangedFcn = createCallbackFcn(app, @TemperatureValueChanged, true);
            app.Temperature.Value = '0';
            app.HumidityPanel = uipanel(app.GridLayout6);
            app.HumidityPanel.Title = 'Humidity';
            app.GridLayout10 = uigridlayout(app.HumidityPanel);
            app.Humidity = uiswitch(app.GridLayout10, 'toggle');
            app.Humidity.Items = {'0', '1'};
            app.Humidity.ValueChangedFcn = createCallbackFcn(app, @HumidityValueChanged, true);
            app.Humidity.Value = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Temperatura

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end